classdef UnderPressure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ProximalPPGAxes          matlab.ui.control.UIAxes
        DistalPPGAxes            matlab.ui.control.UIAxes
        RunButton                matlab.ui.control.StateButton
        SerialPortDropDownLabel  matlab.ui.control.Label
        SerialPortDropDown       matlab.ui.control.DropDown
        SaveDataButton           matlab.ui.control.Button
        BPAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        BPCalc % Description
        Serial % Description
        ProximalLine 
        DistalLine % Description
        DiastolicBPLine
        SystolicBPLine
        CollectedData 
        BPData
        PlotTimer
        BPTimer
        
    end
    
    methods (Access = private)
        
        %Initialized the serial port. 
        %Calling this function resets all existing serial connections.
        function InitializeSerialCommunication(app)
            %initialize serial communication
            delete(instrfindall);
            app.Serial = serial(app.SerialPortDropDown.Value, ...
                'Terminator','CR/LF');
            app.Serial.InputBufferSize = 1024;
            app.Serial.BytesAvailableFcnMode = 'byte'; 
            app.Serial.BytesAvailableFcnCount = 11; 
            app.Serial.BytesAvailableFcn = @app.ReadData;
            

        end
        
        %function is called when data is available for reading from the Arduino.
        function ReadData( app, src, event)
            %read data
            data = fscanf(app.Serial, "%s", 100);
            time = datetime(event.Data.AbsTime);
         
            
            %parse PPG values
            [proximal, distal] = app.BPCalc.ParseDataString(data);
            
            
            %Make sure values are valid
            if ~isdatetime(time)
                return;
            end
            if (isempty(proximal) || isempty(distal))
                return;
            end
            if( proximal > 2^16 || distal >2^16)
                return;
            end
            
            %Save data
            i = length(app.CollectedData) + 1;
            app.CollectedData(i).time = time;
            app.CollectedData(i).proximalPPG = proximal;
            app.CollectedData(i).distalPPG = distal;
            

        end


        function UpdatePlot(app, ~, ~)
            
            %plot data
            t = [app.CollectedData(:).time];
            if isempty(t)
                return
            end
            t = mod(second(t),10); %divide into 10 sec intervals

            %find the start of the last interval
            f = find( t< 0.1 ,1 ,'last');
            if isempty(f)
                f = 1;
            end
            dist = [app.CollectedData(:).distalPPG];
            prox = [app.CollectedData(:).proximalPPG];
            
            %denoise 
            dist = filter(app.BPCalc.BoxFilter, 1, dist);
            prox = filter( app.BPCalc.BoxFilter, 1, prox);

            
            set(app.DistalLine, 'XData', t(f:end), 'YData', dist(f:end));
            set(app.ProximalLine, 'XData', t(f:end), 'YData', prox(f:end));


        end
    
        function GetBP(app, ~, ~)
            
            try
                    
                %figure out when the last segment BP calculated was
                indx = length(app.BPData);
                if indx == 0
                    %process all data
                    dataToProcess = app.CollectedData(1:end);
                else
                    
                    last_time = app.BPData(indx).timeStop;
                     
                    %get the unprocessed data
                    indx = find([app.CollectedData(:).time] > last_time, 1, 'first');
                    dataToProcess = app.CollectedData(indx:end);
                end
    
                
                %segment the data into 5 sec intervals
                dataSegments = [];
                currentindx = 1;
                for t = 1:length(dataToProcess)
                    diff = dataToProcess(t).time- dataToProcess(currentindx).time;
                    if diff >= duration(seconds(5))
                        dataSegments = [dataSegments; currentindx, t];
                        currentindx = t;
                    end
                end
                
                %for each interval, calculate BP
                for r = 1:size(dataSegments,1)
                    data = dataToProcess(dataSegments(r,1): dataSegments(r,2));
                    
                    time = [data(:).time];
                    prox = [data(:).proximalPPG];
                    distal = [data(:).distalPPG];
                    
                    
                    [s,d, ptt, hr] = app.BPCalc.CalculateBP(time, prox, distal );
                    
                    if isnan(s) || isnan(d) || isempty(s) || isempty(s)
                        continue
                    end
                    
                                
                    if (d > s) || (s>180) || (d > 160) || (hr > 160) 
                         continue
                    end
                            
                    %save BP info
                    l = length(app.BPData) + 1;
                    app.BPData(l).timeStart = data(1).time;
                    app.BPData(l).timeStop = data(length(data)).time;
                    app.BPData(l).systolic = s;
                    app.BPData(l).diastolic = d;
                    app.BPData(l).HR = hr;
                    app.BPData(l).PTT = ptt;
                    
                end
             
                
                %update plots
                %bp data
                if isempty(app.BPData)
                    return
                end

                sys = [app.BPData(:).systolic];
                dias = [app.BPData(:).diastolic];
                t = mod(second([app.BPData(:).timeStop]),30);
                %find the start of the last interval
                f = find( t<=30 & t>=20  ,1 ,'last');
                if isempty(f)
                    f = 1;
                else
                    f = f +1;
                end
                
                set( app.DiastolicBPLine, 'XData',t(f:end) , 'YData', dias(f:end));
                set( app.SystolicBPLine, 'XData',t(f:end) , 'YData', sys(f:end));


            catch ME
                app.handleError(ME)
            end
            
            
        end
        
        function handleError(app,error)
            errordlg(getReport(error, 'extended'));
        end
    end
  

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %load COM ports
            app.SerialPortDropDown.Items = seriallist;
            
            %initialize serial
            app.InitializeSerialCommunication
            
            %initialize BP Calculator
            app.BPCalc = BPCalculator;
            
            %initialize data storage variables
            app.CollectedData = struct('time', {}, 'proximalPPG', {}, 'distalPPG', {});
            app.BPData = struct('timeStart', {}, 'timeStop', {}, 'systolic', {}, 'diastolic', {})
            
            %initialize  lines for plotting
            app.ProximalLine = plot(app.ProximalPPGAxes, nan,'-r');
            app.DistalLine = plot(app.DistalPPGAxes, nan, '-b');
            app.DiastolicBPLine = plot(app.BPAxes, nan, '-bo');
            hold(app.BPAxes, 'on')
            app.SystolicBPLine = plot(app.BPAxes,nan, '-bo');
            
            %intialize timers
            app.PlotTimer = timer('TimerFcn',@app.UpdatePlot, 'ExecutionMode', 'fixedRate', 'Period', 0.1);
            app.BPTimer = timer('TimerFcn',@app.GetBP, 'ExecutionMode', 'fixedRate', 'Period', 3 ,'StartDelay', 10);


        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            switch value
                %Button is not pressed
                case 0
                    
                    %stop plotting
                    stop(app.PlotTimer)
                    stop(app.BPTimer)
                    %close serial port
                    fclose(app.Serial);
                    
                    %set button value to Run
                    app.RunButton.Text = 'Run';
                    
                    
                %Button is pressed
                case 1
                    %open serial port
                    try
                        fopen(app.Serial);
                    catch
                        %delete all comm objects and restart
                        app.InitializeSerialCommunication
                        fopen(app.Serial);
                    end 
                    
                    %set button value to Stop
                    app.RunButton.Text = 'Stop';
                    
                    start(app.PlotTimer)
                    start(app.BPTimer)

            end
        end

        % Value changed function: SerialPortDropDown
        function SerialPortDropDownValueChanged(app, event)
            value = app.SerialPortDropDown.Value;
            
            if app.RunButton.Value == 1
                %fclose(app.Serial);
                app.Serial.port = value;
                %fopen(app.Serial);
            else
                app.Serial.port = value;
            end
           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            try
                            
                fclose(app.Serial);
                delete(app.Serial);
                
                stop(app.PlotTimer);
                delete(app.PlotTimer);
                
                stop(app.BPTimer);
                delete(app.BPTimer);
                delete(app);
            end

            
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            
            %Raw Data
            [file,path] = uiputfile('*.xlsx');
            
            time = [app.CollectedData(:).time];
            prox = [app.CollectedData(:).proximalPPG];
            dist = [app.CollectedData(:).distalPPG];
     
            data = table(time',prox',dist','VariableNames', {'Time', 'Proximal_PPG', 'Distal_PPG'});
            writetable(data, fullfile(path,file), 'Sheet', 1)

            %BP Data
      
            timeStart = [app.BPData(:).timeStart];
            timeStop = [app.BPData(:).timeStop];
            systolic = [app.BPData(:).systolic];
            diastolic = [app.BPData(:).diastolic];
            heartrate = [app.BPData(:).HR];
            ptt = [app.BPData(:).PTT];
            bpdata = table(timeStart', timeStop', systolic', diastolic', heartrate', ptt',...
                'VariableNames',{'Start', 'Stop', 'Systolic', 'Diastolic', 'HR', 'PTT'});
            writetable(bpdata, fullfile(path,file), 'Sheet',2)
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProximalPPGAxes = uiaxes(app.UIFigure);
            title(app.ProximalPPGAxes, 'Proximal PPG')
            xlabel(app.ProximalPPGAxes, 'Time (s)')
            app.ProximalPPGAxes.XLim = [0 10];
            app.ProximalPPGAxes.ZTick = [];
            app.DistalPPGAxes = uiaxes(app.UIFigure);
            title(app.DistalPPGAxes, 'Distal PPG')
            xlabel(app.DistalPPGAxes, 'Time (s)')
            app.DistalPPGAxes.XLim = [0 10];
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.SerialPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialPortDropDownValueChanged, true);
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.BPAxes = uiaxes(app.UIFigure);
            title(app.BPAxes, 'Blood Pressure')
            xlabel(app.BPAxes, 'Time (s)')
            app.BPAxes.XLim = [0 30];
        end
    end

    methods (Access = public)

        % Construct app
        function app = UnderPressure

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end